classdef MassSpringDamperSystem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        MasskgEditFieldLabel            matlab.ui.control.Label
        MasskgEditField                 matlab.ui.control.NumericEditField
        SpringConstantNm1EditFieldLabel  matlab.ui.control.Label
        SpringConstantNm1EditField      matlab.ui.control.NumericEditField
        DamperCoefficientNsm1EditFieldLabel  matlab.ui.control.Label
        DamperCoefficientNsm1EditField  matlab.ui.control.NumericEditField
        HeightofstepinputNEditFieldLabel  matlab.ui.control.Label
        HeightofstepinputNEditField     matlab.ui.control.NumericEditField
        DrawButton                      matlab.ui.control.Button
        MassSpringDamperSystemLabel     matlab.ui.control.Label
        YasserQureshiLabel              matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            % Gathering user values
            m = app.MasskgEditField.Value ;
            k = app.SpringConstantNm1EditField.Value ;
            b = app.DamperCoefficientNsm1EditField.Value ;
            h = app.HeightofstepinputNEditField.Value ;
            
            % Creating transfer function
            sys = tf(1, [m b k]) ;
            
            % Creating step response
            [y, t] = step(sys * h) ;
            
            % Plotting step response
            plot(app.UIAxes, t, y) ;
            
            
            %%  ---------------- Animation --------------------
            % Please note that to create the animation, I took inspiration
            % from open source code found online. I adapted this to create
            % a spring-mass-damper system
            
            % Initial Conditions
            X0 = [h; -1] ;
            
            % Setting up the time vector
            tfinal = 200;
            
            % Dynamics of the system with controls
            % Setting up state space with controls:
            AA = [0 1; -k/m -b/m];
            BA = [0; 1/m];
            CA = [1 1];
            DA = 1;
            
            % Optimal Control Law for the system
            % Setting up Q & R matrices
            Q = [1 0; 0 10];
            R = 1;
            N = 0;
            
            % K = Kalman Gain
            [K,~,~] = lqr(AA,BA,Q,R,N);
            
            % Setting up a dummy B matrix for the initial command
            B_kal = [0;0];
            
            % With Control Input
            SYSA = ss(AA-K*BA,B_kal,CA,DA);
            
            % initial state response
            [YA,tA,~] = initial(SYSA,X0,tfinal);
            
            for k=1:1:length(tA)
                y = YA(k,:);
                
                x = y(1);
                
                % Dimensions
                W = 2;   % mass width
                H = 1;   % mass height
                
                % Mass
                y = H/2;
                
                plot(app.UIAxes2, [-10 10],[0 0],'w','LineWidth',2)
                hold(app.UIAxes2, 'on')
                % Draw the wall and the floor
                plot(app.UIAxes2,[-10 -10 10],[2.5 0 0],'w','LineWidth',4, "Color", 'k');

                % Draw the mass
                rectangle(app.UIAxes2, 'Position',[x-W/2,y-H/2,W,H],'Curvature',.1,'FaceColor',[1 0.1 0.1],'EdgeColor',[1 1 1]);

                % Draw the spring
                plot(app.UIAxes2,[-10, -9, -9:(9+x-(W/2))/9:x-(W/2), x-(W/2), x-(W/4)],0.75+[0 0 0 .25 -.25 .25 -.25 .25 -.25 .25 -.25 0 0 0],'r','LineWidth',2);
                % Draw the damper
                plot(app.UIAxes2,[-10 x-5],0.15+[0 0],'g',[x-5 x-5],0.15+[.10 -.10],'g',[x-20, x-W, x-W, x-20],0.15+[.1 .1 -.1 -.1],'g',[ x-W, x-(W/2)], [0.15 0.15],'g',[ x-(W/2), x-(W/2)], 0.15+[0.1 -0.1],'g','LineWidth',2);
                
                % Setting up the window
                app.UIAxes2.XLim = [-10 10];
                app.UIAxes2.YLim = [-2.5 2.5];
                set(app.UIAxes2, 'visible', 'off')
                set(app.UIAxes2, 'xtick', [])
                
                drawnow
                
                hold(app.UIAxes2, 'off')
                
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {534, 534};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {295, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MasskgEditFieldLabel = uilabel(app.LeftPanel);
            app.MasskgEditFieldLabel.Text = 'Mass (kg)';
            app.MasskgEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SpringConstantNm1EditFieldLabel = uilabel(app.LeftPanel);
            app.SpringConstantNm1EditFieldLabel.Text = 'Spring Constant (Nm^-1)';
            app.SpringConstantNm1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.DamperCoefficientNsm1EditFieldLabel = uilabel(app.LeftPanel);
            app.DamperCoefficientNsm1EditFieldLabel.Text = 'Damper Coefficient (Nsm^-1)';
            app.DamperCoefficientNsm1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.HeightofstepinputNEditFieldLabel = uilabel(app.LeftPanel);
            app.HeightofstepinputNEditFieldLabel.Text = 'Height of step input (N)';
            app.HeightofstepinputNEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DrawButton = uibutton(app.LeftPanel, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.MassSpringDamperSystemLabel = uilabel(app.LeftPanel);
            app.MassSpringDamperSystemLabel.Text = 'Mass-Spring-Damper System';
            app.YasserQureshiLabel = uilabel(app.LeftPanel);
            app.YasserQureshiLabel.Text = 'Yasser Qureshi';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = {'Please insert numerical values below to create '; 'a step response plot and an animation for your '; 'given mass-spring-damper system:'};
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Step Response')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'x')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MassSpringDamperSystem

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end